classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QRSDetectorUIFigure             matlab.ui.Figure
        Button                          matlab.ui.control.Button
        SamplingFrequencyDropDownLabel  matlab.ui.control.Label
        SamplingFrequencyDropDown       matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function callbackStart(app, event)


            if (app.SamplingFrequencyDropDown.Value=='500 Hz')
               Freq = 500;
           else 
               Freq = 360;
           end
      %     Fs=app.SamplingFrequencyDropDown.Value; 
            Fs=Freq;
            [filename, pathname] = uigetfile('*.*');
            filewithpath=strcat(pathname, filename);
            ecg=load(filename);
            ecgsig=(ecg.val)./200; %Normalize gain
            t=1:length(ecgsig);
            tx=t./Fs; %time vector
            
            wt = modwt(ecgsig, 4, 'sym4');
            wtrec = zeros(size(wt));
            wtrec(3:4,:) = wt(3:4,:);
            
            y = imodwt(wtrec,'sym4');
            y = abs(y).^2; 
            
            avg=mean(y);
            [Rpeak, locs] = findpeaks(y, t, 'MinPeakHeight', 8*avg, 'MinPeakDistance', 50);
            
            nohb = length(locs);
            timelimit=length(ecgsig)/Fs;
            hbpermin=(nohb*60)/timelimit;
  %          disp(strcat('Heart Rate= ', num2str(hbpermin)))
            
            %subplot(211)
        plot(app.UIAxes, ecgsig, 'cyan');
       % xlim(app.UIAxes,[0, timelimit]);
  %      grid on;
        xlabel(app.UIAxes, 'Seconds')
        title(app.UIAxes, 'ECG Signal', "Color",'cyan')

       % subplot(212)
       
        plot(app.UIAxes2, y, 'green')
    %    grid on;
    %    xlim([app.UIAxes2, 0, length(ecgsig)]);
        hold(app.UIAxes2)
        plot(app.UIAxes2, locs, Rpeak, 'rO', "MarkerSize",10 )
        xlabel(app.UIAxes2, 'Samples')
        title(app.UIAxes2, strcat('R Peaks found'), "Color" ,'green')
     
       
textLabel = sprintf('%s', num2str(hbpermin));
app.Label.Text = textLabel;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QRSDetectorUIFigure = uifigure('Visible', 'off');
            app.QRSDetectorUIFigure.Name = 'QRS Detector';
            app.Button = uibutton(app.QRSDetectorUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @callbackStart, true);
            app.Button.Icon = '25706.png';
            app.Button.Text = '';
            app.SamplingFrequencyDropDownLabel = uilabel(app.QRSDetectorUIFigure);
            app.SamplingFrequencyDropDownLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyDropDown = uidropdown(app.QRSDetectorUIFigure);
            app.SamplingFrequencyDropDown.Items = {'360 Hz', '500 Hz'};
            app.SamplingFrequencyDropDown.Value = '360 Hz';
            app.Image = uiimage(app.QRSDetectorUIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'asdadsda22.png';
            app.Label = uilabel(app.QRSDetectorUIFigure);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.QRSDetectorUIFigure);
            xlabel(app.UIAxes, 'Seconds')
            app.UIAxes2 = uiaxes(app.QRSDetectorUIFigure);
            xlabel(app.UIAxes2, 'Samples')
            app.UIAxes2.YTickLabel = {'0'; '0.5'; '1'};
            % Show the figure after all components are created
            app.QRSDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QRSDetectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QRSDetectorUIFigure)
        end
    end
end